classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        UIAxes    matlab.ui.control.UIAxes
        Button    matlab.ui.control.Button
        Label1    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function EnterfileButtonPushed(app, event)
            [FileId,~,~]=uigetfile;
            [~,~,data]=xlsread(FileId);
            for i=1:2:size(data,2)-1
                date = cell2mat(data(1:end,i));
                gdp = cell2mat(data(1:end,i+ 1));
                formatIn='dd.mm.yyyy';
                t=datenum(date,formatIn);
                hold(app.UIAxes,"on")
                app.UIAxes.XTick=t;
                plot(app.UIAxes,t,gdp);
                app.UIAxes.YTick=unique(gdp);
                app.UIAxes.YTickLabel=unique(gdp);
                grid(app.UIAxes,"on");
            end
            datetick(app.UIAxes,'x','dd.mm.yy','keepticks');
            re=sum(gdp)/length(gdp);
            app.Label1.Text="средний ввп-"+num2str(re);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @EnterfileButtonPushed, true);
            app.Button.Text = {'магия цифр'; ''};
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end